classdef playerUIfntest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ImageBackgroud     matlab.ui.control.Image
        LordButton         matlab.ui.control.Button
        giveupButton       matlab.ui.control.Button
        startButton        matlab.ui.control.Button
        ButtonAfter        matlab.ui.control.Button
        afterPlayerLabel   matlab.ui.control.Label
        Player2ActorLabel  matlab.ui.control.Label
        ButtonBefore       matlab.ui.control.Button
        beforePlayerLabel  matlab.ui.control.Label
        Player1ActorLabel  matlab.ui.control.Label
        ButtonMe           matlab.ui.control.Button
        myActor            matlab.ui.control.Label
        me                 matlab.ui.control.Label
        ImageClock         matlab.ui.control.Image
        clockLabel         matlab.ui.control.Label
        playButton         matlab.ui.control.Button
        passButton         matlab.ui.control.Button
        card1Button        matlab.ui.control.StateButton
        card2Button        matlab.ui.control.StateButton
        card3Button        matlab.ui.control.StateButton
        card4Button        matlab.ui.control.StateButton
        card5Button        matlab.ui.control.StateButton
        card6Button        matlab.ui.control.StateButton
        card7Button        matlab.ui.control.StateButton
        card8Button        matlab.ui.control.StateButton
        card9Button        matlab.ui.control.StateButton
        card10Button       matlab.ui.control.StateButton
        card11Button       matlab.ui.control.StateButton
        card12Button       matlab.ui.control.StateButton
        messageLabel       matlab.ui.control.Label
        Button11           matlab.ui.control.Button
        Button12           matlab.ui.control.Button
        Button13           matlab.ui.control.Button
        Button14           matlab.ui.control.Button
        Button15           matlab.ui.control.Button
        Button16           matlab.ui.control.Button
        Button17           matlab.ui.control.Button
        Button18           matlab.ui.control.Button
        Button19           matlab.ui.control.Button
        Button20           matlab.ui.control.Button
        Button10           matlab.ui.control.Button
        Button1            matlab.ui.control.Button
        Button2            matlab.ui.control.Button
        Button3            matlab.ui.control.Button
        Button4            matlab.ui.control.Button
        Button5            matlab.ui.control.Button
        Button6            matlab.ui.control.Button
        Button7            matlab.ui.control.Button
        Button8            matlab.ui.control.Button
        Button9            matlab.ui.control.Button
        card13Button       matlab.ui.control.StateButton
        card14Button       matlab.ui.control.StateButton
        card15Button       matlab.ui.control.StateButton
        card16Button       matlab.ui.control.StateButton
        card17Button       matlab.ui.control.StateButton
        card18Button       matlab.ui.control.StateButton
        card19Button       matlab.ui.control.StateButton
        card20Button       matlab.ui.control.StateButton
        Image              matlab.ui.control.Image
        Image_2            matlab.ui.control.Image
        Cardnum1           matlab.ui.control.Label
        Cardnum2           matlab.ui.control.Label
        nwin               matlab.ui.control.Image
        dwin               matlab.ui.control.Image
        alarm1             matlab.ui.control.Image
        alarm2             matlab.ui.control.Image
        hatbefore          matlab.ui.control.Image
        hatafter           matlab.ui.control.Image
        hatme              matlab.ui.control.Image
        boom               matlab.ui.control.Image
        wangzha            matlab.ui.control.Image
        airplane           matlab.ui.control.Image
    end

    
    properties (Access = public)
        start;
        MasterProcessor; 
        % param for rob 
     %   firstDecide;
     %   lastDecide;
        currentDecider;
        %%%
        lord;
        cardList;     % list of cards in hands
        buttonlist;   % list of cards on desk
        lordState;
        
        playCards;
        lastCards;
        
       
    end
    
    methods (Access = public)
        
        function head(app,list)
            app.ButtonBefore.Icon=[int2str(list(1)) '.jpg'];
            app.ButtonAfter.Icon=[int2str(list(2)) '.jpg'];
            app.ButtonMe.Icon=[int2str(list(3)) '.jpg'];  
            
            if list(3)==102
                app.me.Text='漂亮mm在线发牌';
                app.beforePlayerLabel.Text='土财主爱演炸弹秀';
                app.afterPlayerLabel.Text='不想努力的请找富婆我';
            elseif list(3)==101
                app.beforePlayerLabel.Text='漂亮mm在线发牌';
                app.afterPlayerLabel.Text='土财主爱演炸弹秀';
                app.me.Text='不想努力的请找富婆我'; 
 
            else
                app.afterPlayerLabel.Text='漂亮mm在线发牌';
                app.me.Text='土财主爱演炸弹秀';
                app.beforePlayerLabel.Text='不想努力的请找富婆我';  
 
            end
            
        end
        
        function updatePlayer(app, Aafter, Abefore, Aself)
            if Aself == 0
                app.myActor.Text = 'Farmer';
            elseif Aself == 1
                app.myActor.Text = 'Lord';
                app.hatme.Visible='on';
            end
            app.myActor.Visible='on';
            app.beforePlayerLabel.Visible = 'on';
            app.Player1ActorLabel.Visible = 'on';
            if Abefore == 0
                app.Player1ActorLabel.Text = 'Farmer';
            elseif Abefore == 1
                app.Player1ActorLabel.Text = 'Lord';
                app.hatbefore.Visible='on';
            end
            %%
            app.afterPlayerLabel.Visible = 'on';
            app.Player2ActorLabel.Visible = 'on';
            if Aafter == 0
                app.Player2ActorLabel.Text = 'Farmer';
            elseif Aafter == 1
                app.Player2ActorLabel.Text = 'Lord';
                app.hatafter.Visible='on';
            end
            %%
            app.LordButton.Visible='off';
            app.LordButton.Enable='off';
            app.giveupButton.Visible='off';
            app.giveupButton.Enable='off';
            app.messageLabel.Text = 'someone is choosing cards ';
        end
        
        function  showEffect(app,kind)
            if kind==9
                app.wangzha.Visible='on';
                pause(1.5)
                app.wangzha.Visible='off';
            elseif kind==8
                app.boom.Visible='on';
                pause(1.5);
                app.boom.Visible='off';
            elseif kind==7            
                app.airplane.Visible='on';
                pause(1.5);
                app.airplane.Visible='off';
                
            end    
        
        end


        function   showCardsButton(app,noskip)
            app.playButton.Visible='on';
            app.playButton.Enable='on';
            if ~noskip
                app.passButton.Visible='on';
                app.passButton.Enable='on';
            end
            for i=1:20
               % app.cardList{i}.Visible = 'on';
                app.cardList{i}.Enable = 'on';
            end
            
            clock=25;
            while clock>=21
                app.ImageClock.Visible='on';
                app.clockLabel.Visible='on';
                
                app.clockLabel.Text=int2str(clock);
                clock=clock-1;
                pause(1);
            end    
            if clock<0
                if  app.passButton.Enable=='on'
                    app.clockLabel.Visible='off';      
                    app.playCards=[];
                    app.playButton.Visible = 'off';
                    app.playButton.Enable = 'off';
                    app.passButton.Visible = 'off';
                    app.passButton.Enable = 'off';  
                    for i = 1:20
                        app.cardList{i}.Value = 0;
                        app.cardList{i}.Enable='off';
                    end
                    pass=true;
                    app.MasterProcessor.loop(pass,-1);
                elseif app.passButton.Enable=='off'
                    app.messageLabel.Text='他们等的花儿都谢了！';
           
                end
            end
            
        end


        function showCards(app, split, Np1, Np2)
            app.Cardnum1.Visible = 'on';
            app.Cardnum1.Text = int2str(Np1);
            if Np1<3
                app.alarm1.Visible='on';
                pause(1);
                app.alarm1.Visible='off';
            end
            
            if Np2<3
                app.alarm2.Visible='on';
                pause(1);
                app.alarm2.Visible='off';        
            end
            
            
            app.Cardnum2.Visible = true;
            app.Cardnum2.Text = int2str(Np2);
   
            for i = 1:20
                if i <= length(split)
                    app.cardList{i}.Visible = 'on';
                    app.cardList{i}.Text = int2str(split(i));
                    app.cardList{i}.Icon = [int2str(split(i)) '.jpg'];
                else 
                    app.cardList{i}.Visible = 'off';
                end             
            end

        end
    
        
        function showLC(app,lordCards,flag)
            if flag
                app.Button1.Text = int2str(lordCards(1));
                app.Button1.Icon = [int2str(lordCards(1)) '.jpg'];
                app.Button2.Text = int2str(lordCards(2));
                app.Button2.Icon = [int2str(lordCards(2)) '.jpg'];
                app.Button3.Text = int2str(lordCards(3));
                app.Button3.Icon = [int2str(lordCards(3)) '.jpg'];
                app.Button1.Visible = 'on';
                app.Button2.Visible = 'on';
                app.Button3.Visible = 'on';
            else 
                app.Button1.Visible = 'off';
                app.Button2.Visible = 'off';
                app.Button3.Visible = 'off';
            end                 
        end

    
        function showRob(app,currentDecider)
            app.LordButton.Visible='on';
            app.LordButton.Enable='on';
            app.giveupButton.Visible='on';
            app.giveupButton.Enable='on';
         %   app.firstDecide=firstDecide;
         %   app.lastDecide=lastDecide;
            app.currentDecider=currentDecider;            
        end
        
        
        function getLordState(app,over)
            if over
                app.LordButton.Visible = 'off';
                app.giveupButton.Visible = 'off';     
            else
                app.LordButton.Visible = 'on';
                app.giveupButton.Visible = 'on';
                app.SubmitButton.Visible = 'on';
            end          
        end
        
        
        function gameover(app,winner,act)
            if act==0
                app.nwin.Visible='on';
            else 
                app.dwin.Visible='on';
            end
            app.messageLabel.Text =  ['game over ' winner ' is the winner'] ;    
            
        end
        
       
        function twopass(app)
            app.lastCards=[];
            app.passButton.Enable='off';  % two people pass,you can not pass
            app.messageLabel.Text="they all pass ";
            app.passButton.Enable='off';
        end


        function [kindArray]=kind(app,x)
         
            tempArray=[];
            size=length(x);
            if size==0
                kindArray=[0,0];
            else
                
                for i=1:size
                    if x(i)==53 || x(i)==54
                        tempArray(end+1) = x(i);
                    else
                       temp = fix(x(i)/4)+3;
                       if mod(x(i),4)==0
                           temp=temp-1;    
                       end
                       tempArray(end+1) = temp;   %change num to cards 3-15 tempArray
                            
                    end
                end
            end
           
            
            if size==2
                 if x(1)==53 & x(2)==54
                    kindArray=[9,66];    %wangzha 
                 elseif  tempArray(1)==tempArray(2)
                    kindArray=[2,tempArray(1)];
                 else 
                    kindArray=[-1,-1];
                 end                              
           
            
            elseif size==1
                
                kindArray=[1,tempArray(1)];
                          
            elseif size>=3
                numResult=zeros(15);   %numresult record every num appear how many times 0 1 2 is always zero
                for i =3:15
                    count = 0;
                    for j=1:length(tempArray)
                        if tempArray(j)==i
                            count=count+1;
                        end
                    end
                    numResult(i)=count;               
                end
                
              %  for i =3:15
              %      app.MessageTextArea.Value=['numresult',int2str(i)+":" + int2str( numResult(i) )];
              %      pause(0.6);
              %  end
                
            
                if size==4
                    if ismember(4,numResult)        
                       address=find(numResult==4);
                       kindArray=[8,address(1)];   %address is the card's num
                    elseif ismember(3,numResult)
                       address = find(numResult==3);
                       kindArray=[5,address(1)];
                    else 
                        kindArray=[-1,-1];   
                    end 
               
                   % elseif (num1==1&&num2==3) || (num1==3 && num2==1)
                   
                elseif size==5
                    shunzi=[];
                    for i=3:15
                        if numResult(i)==1
                            shunzi(end+1)=i;          
                        end
                    end
                    
                    if length(shunzi)~=5
                        kindArray=[-1,-1];
                    else
                        flag=1;
                        for j=2:5
                            if shunzi(j)~=shunzi(j-1)+1
                                flag=0;
                            end
                        end
                        
                        if flag 
                            kindArray=[3,shunzi(1)];   %the smallest num in shunzi
                        else 
                            kindArray=[-1,-1];
                        end
                    end
                 % from size>=6 mark the biiger num   
                elseif size==6
                  if  ismember(4,numResult)  % sidaier
                      address=find(numResult==4);        
                      kindArray=[6,address(1)];   
                  elseif ismember(2,numResult)  
                      count2=0;
                      for i=3:15
                          if numResult(i)==2
                              count2=count2+1;
                              biggest=i;
                          end
                      end
                      
                      if count2==3
                          kindArray=[4,biggest];  %the bigest num in shuangshunzi
                      else
                          kindArray=[-1,-1];
                      end
                  else 
                      kindArray=[-1,-1]; 
                          
                  end
               
                    
                elseif size==8
                    
                    if ismember(3,numResult)
                        count3=0;
                       for i=3:15
                          if numResult(i)==3
                              count3=count3+1;
                              biggest=i;
                          end
                       end   
                      if count3==2
                          kindArray=[7,biggest] ;%feiji 
                      else
                          kindArray=[-1,-1];
                      end
                    else
                         kindArray=[-1,-1];   
                    end
                    
               else
                    kindArray=[-1,-1];   %if not in ant type it is ilegal 
               end
                  
            end         
        end
        
%         function showPlayButton(app)
%             app.playCards=[];
%             app.playButton.Visible = 'on';
%            
% 
%             for i=1:20
%                 if app.cardList{i}.Visible & app.cardList{i}.Value %when we press button the value will become 1
%                     app.playCards(end+1)=str2num(app.cardList{i}.Text);
%                 end
%             end
%             
%             
%             size=length(app.playCards);
%             tempArray=[];
%             
%             for i=1:size
%                 temp = fix(app.playCards(i)/4)+3;
%                 if mod(app.playCards(i),4)==0
%                    temp=temp-1;    
%                 end
%                 tempArray(end+1) = temp;
% 
%             end
%             
%             if size==2
%                  if app.playCards(1)==53 & app.playCards(2)==54
%                       app.playButton.Enable='on';    
%                  end
%                    
%             elseif ~isempty(app.playCards) & length(app.playCards)>=length(app.lastCards)
%             
%                 if size==1
%                     app.MessageTextArea.Value = [  'onecard',int2str(size)];
%                     app.playButton.Enable='on';
%                     
%                 elseif size==2
%                     app.MessageTextArea.Value = [  'twocards'];
%                     if app.playCards(1)==app.playCards(2)
%                         app.playButton.Enable='on';
%                     end    
%                 elseif  size==3
%                     app.MessageTextArea.Value = [  'threecards'];
%                     if app.playCards(1)==app.playCards(2)==app.playCards(3)
%                         app.playButton.Enable='on';
%                     end       
%                 elseif size==4
%                     numResult=zeros(13);
%                     for i =1:13
%                         count = 0;
%                         for j=1:length(tempArray)
%                             if tempArray(j)==i
%                                 count=count+1;
%                             end
%                         end
%                         numResult(i)=count;               
%                     end
%                 
%                 
%                     if ismember(4,numResult)        
%                        address=find(numResult==4);
%                        app.MessageTextArea.Value = [  'boom'];
%                        app.playButton.Enable='on';
%                     elseif ismember(3,numResult)
%                        app.playButton.Enable='on';
%                        app.MessageTextArea.Value = [ 'san dai yi'];
%                     end 
%                
%                    % elseif (num1==1&&num2==3) || (num1==3 && num2==1)
%                    
%                 elseif size==5
%                     app.playButton.Enable='on'; 
%                 elseif size==17
%                     app.playButton.Enable='on';
%                else
%                     app.playButton.Enable='off';
%                 end
%                   
%             end
%                % app.playButton.Enable='on';
%          
%                
%             
%         end
        
        function showDesk(app, split,mycards)
            for i =1:20
                app.buttonlist{i}.Visible='off';   
            end
            for i=length(mycards)+1:20
                app.cardList{i}.Visible='off';
            end
            
            for i=1:length(split)
                app.buttonlist{i}.Visible ='on';
                app.buttonlist{i}.Text =  int2str(split(i));
                app.buttonlist{i}.Icon = [int2str(split(i)) '.jpg'];
            end
            app.lastCards=split;
        end  
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, master)
          
            app.start = false;
            app.lord = false;
            app.lordState = false;
            app.lastCards=[];
            app.playCards=[];
            app.cardList = {app.card1Button,app.card2Button,app.card3Button,app.card4Button,app.card5Button,...
                app.card6Button,app.card7Button,app.card8Button,app.card9Button,app.card10Button,...
                app.card11Button,app.card12Button,app.card13Button,app.card14Button,app.card15Button,...
                app.card16Button,app.card17Button,app.card18Button,app.card19Button,app.card20Button};
        
            app.buttonlist={app.Button1,app.Button2,app.Button3,app.Button4,app.Button5,app.Button6,...
                app.Button7,app.Button8,app.Button9,app.Button10,app.Button11,app.Button12,app.Button13...
                app.Button14,app.Button15,app.Button16,app.Button17,app.Button18,app.Button19,app.Button20,};
            %app.MessageTextArea.Value = 'press start button.';
                app.messageLabel.Text='press start button.';
        end

        % Callback function
        function MessageTextAreaValueChanged(app, event)
            value = app.MessageTextArea.Value;
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            app.clockLabel.Visible='off';
            canPlay=false;
            app.playCards = [];
            for i = 1:length(app.buttonlist)
                if app.cardList{i}.Visible & app.cardList{i}.Value
                   %when we press button the value will become 1
                    app.playCards(end+1)=str2num(app.cardList{i}.Text);
                end
            end
            
            % judge is the cards legal

          
            lastkind=app.kind(app.lastCards);  %last cards  
            nowkind=app.kind(app.playCards);  %cards played   k小写

            if lastkind(1)==0         %if two pass or you are the first one, only need your play is legal
                if nowkind(1)<=0
                    app.messageLabel.Text="illegal cards!!!!!  choose again ";
                else 
                    canPlay=true;
                end 
            else 
                if nowkind(1)==9  %wanzgha
                    canPlay=true;
                elseif nowkind(1)==8  %boom
                    if lastkind(1)~=8
                        canPlay=true;   

                    else                             % last is boom too
                        if lastkind(2)<nowkind(2)
                            canPlay=true;
                        end
                    end
                elseif nowkind(1)==lastkind(1) && nowkind(2)>lastkind(2)
                    canPlay=true;          
                end
            end
   
        
            if canPlay
                % he may choose nothing but press the button
                app.messageLabel.Text = ' cards have been played' ;
                app.playButton.Visible = 'off';
                app.playButton.Enable = 'off';
                app.passButton.Visible = 'off';
                app.passButton.Enable = 'off';          
    
                % make value back to 0
                for i = 1:20
                    app.cardList{i}.Value = 0;
                    app.cardList{i}.Enable='off';
                end
                
                pass=false;
                app.MasterProcessor.loop(pass,nowkind(1));
            else 
                app.messageLabel.Text="illegal cards, choose again ";
            end
            
 
        end

        % Button pushed function: passButton
        function passButtonPushed(app, event)
            app.clockLabel.Visible='off';      
            app.playCards=[];
            app.playButton.Visible = 'off';
            app.playButton.Enable = 'off';
            app.passButton.Visible = 'off';
            app.passButton.Enable = 'off';  
            for i = 1:20
                app.cardList{i}.Value = 0;
                app.cardList{i}.Enable='off';
            end
            
            pass=true;
            app.MasterProcessor.loop(pass,-1);
            
            
        end

        % Button pushed function: LordButton
        function LordButtonValueChanged(app, event)
            %value = app.LordButton.Value;
            %app.LordButton.Visible='off';
            app.LordButton.Enable='off';
            app.giveupButton.Enable='off';
            decision=1;
            app.MasterProcessor.nextRob(app.currentDecider,decision);
        end

        % Button pushed function: giveupButton
        function giveupButtonValueChanged(app, event)
           % value = app.giveupButton.Value;
            %app.giveupButton.Visible='off';
            app.LordButton.Enable='off';
            app.giveupButton.Enable='off';
            decision=0;
            app.MasterProcessor.nextRob(app.currentDecider,decision);
   
        end

        % Callback function
        function card1ButtonValueChanged(app, event)
            value = app.card1Button.Value;
           % app.showPlayButton();
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            for i=1:20
                app.buttonlist{i}.Visible='off';
            end

            app.messageLabel.Text = 'welcome to pocker game !' ;
            temp = app.MasterProcessor.everyone_ready+1;
            app.MasterProcessor.everyone_ready=temp;
            app.startButton.Visible = 'off';
            if temp==3
                app.MasterProcessor.startGame;
                app.start = true;
            end
                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImageBackgroud = uiimage(app.UIFigure);
            app.ImageBackgroud.ImageSource = 'table.jpg';
            app.LordButton = uibutton(app.UIFigure, 'push');
            app.LordButton.ButtonPushedFcn = createCallbackFcn(app, @LordButtonValueChanged, true);
            app.LordButton.Visible = 'off';
            app.LordButton.Text = 'lord';
            app.giveupButton = uibutton(app.UIFigure, 'push');
            app.giveupButton.ButtonPushedFcn = createCallbackFcn(app, @giveupButtonValueChanged, true);
            app.giveupButton.Visible = 'off';
            app.giveupButton.Text = 'giveup';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.ButtonAfter = uibutton(app.UIFigure, 'push');
            app.ButtonAfter.Icon = 'chushi.jpg';
            app.ButtonAfter.Text = '';
            app.afterPlayerLabel = uilabel(app.UIFigure);
            app.afterPlayerLabel.Text = 'afterPlayer';
            app.Player2ActorLabel = uilabel(app.UIFigure);
            app.Player2ActorLabel.Visible = 'off';
            app.Player2ActorLabel.Text = 'Player2Actor';
            app.ButtonBefore = uibutton(app.UIFigure, 'push');
            app.ButtonBefore.Icon = 'chushi.jpg';
            app.ButtonBefore.Text = '';
            app.beforePlayerLabel = uilabel(app.UIFigure);
            app.beforePlayerLabel.Text = 'beforePlayer';
            app.Player1ActorLabel = uilabel(app.UIFigure);
            app.Player1ActorLabel.Visible = 'off';
            app.Player1ActorLabel.Text = 'Player1Actor';
            app.ButtonMe = uibutton(app.UIFigure, 'push');
            app.ButtonMe.Icon = 'chushi.jpg';
            app.ButtonMe.Text = '';
            app.myActor = uilabel(app.UIFigure);
            app.myActor.Visible = 'off';
            app.myActor.Text = 'Actor';
            app.me = uilabel(app.UIFigure);
            app.me.Text = 'me';
            app.ImageClock = uiimage(app.UIFigure);
            app.ImageClock.Visible = 'off';
            app.ImageClock.ImageSource = 'clock.jpg';
            app.clockLabel = uilabel(app.UIFigure);
            app.clockLabel.Visible = 'off';
            app.clockLabel.Text = '25';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Enable = 'off';
            app.playButton.Visible = 'off';
            app.playButton.Text = 'play';
            app.passButton = uibutton(app.UIFigure, 'push');
            app.passButton.ButtonPushedFcn = createCallbackFcn(app, @passButtonPushed, true);
            app.passButton.Enable = 'off';
            app.passButton.Visible = 'off';
            app.passButton.Text = 'pass';
            app.card1Button = uibutton(app.UIFigure, 'state');
            app.card1Button.Enable = 'off';
            app.card1Button.Visible = 'off';
            app.card1Button.Text = 'card1';
            app.card2Button = uibutton(app.UIFigure, 'state');
            app.card2Button.Enable = 'off';
            app.card2Button.Visible = 'off';
            app.card2Button.Text = 'card2';
            app.card3Button = uibutton(app.UIFigure, 'state');
            app.card3Button.Enable = 'off';
            app.card3Button.Visible = 'off';
            app.card3Button.Text = 'card3';
            app.card4Button = uibutton(app.UIFigure, 'state');
            app.card4Button.Enable = 'off';
            app.card4Button.Visible = 'off';
            app.card4Button.Text = 'card4';
            app.card5Button = uibutton(app.UIFigure, 'state');
            app.card5Button.Enable = 'off';
            app.card5Button.Visible = 'off';
            app.card5Button.Text = 'card5';
            app.card6Button = uibutton(app.UIFigure, 'state');
            app.card6Button.Enable = 'off';
            app.card6Button.Visible = 'off';
            app.card6Button.Text = 'card6';
            app.card7Button = uibutton(app.UIFigure, 'state');
            app.card7Button.Enable = 'off';
            app.card7Button.Visible = 'off';
            app.card7Button.Text = 'card7';
            app.card8Button = uibutton(app.UIFigure, 'state');
            app.card8Button.Enable = 'off';
            app.card8Button.Visible = 'off';
            app.card8Button.Text = 'card8';
            app.card9Button = uibutton(app.UIFigure, 'state');
            app.card9Button.Enable = 'off';
            app.card9Button.Visible = 'off';
            app.card9Button.Text = 'card9';
            app.card10Button = uibutton(app.UIFigure, 'state');
            app.card10Button.Enable = 'off';
            app.card10Button.Visible = 'off';
            app.card10Button.Text = 'card10';
            app.card11Button = uibutton(app.UIFigure, 'state');
            app.card11Button.Enable = 'off';
            app.card11Button.Visible = 'off';
            app.card11Button.Text = 'card11';
            app.card12Button = uibutton(app.UIFigure, 'state');
            app.card12Button.Enable = 'off';
            app.card12Button.Visible = 'off';
            app.card12Button.Text = 'card12';
            app.messageLabel = uilabel(app.UIFigure);
            app.messageLabel.Text = {'message'; ''; ''};
            app.Button11 = uibutton(app.UIFigure, 'push');
            app.Button11.Visible = 'off';
            app.Button11.Text = 'Button11';
            app.Button12 = uibutton(app.UIFigure, 'push');
            app.Button12.Visible = 'off';
            app.Button12.Text = 'Button12';
            app.Button13 = uibutton(app.UIFigure, 'push');
            app.Button13.Visible = 'off';
            app.Button13.Text = 'Button1';
            app.Button14 = uibutton(app.UIFigure, 'push');
            app.Button14.Visible = 'off';
            app.Button14.Text = 'Button1';
            app.Button15 = uibutton(app.UIFigure, 'push');
            app.Button15.Visible = 'off';
            app.Button15.Text = 'Button1';
            app.Button16 = uibutton(app.UIFigure, 'push');
            app.Button16.Visible = 'off';
            app.Button16.Text = 'Button1';
            app.Button17 = uibutton(app.UIFigure, 'push');
            app.Button17.Visible = 'off';
            app.Button17.Text = 'Button1';
            app.Button18 = uibutton(app.UIFigure, 'push');
            app.Button18.Visible = 'off';
            app.Button18.Text = 'Button1';
            app.Button19 = uibutton(app.UIFigure, 'push');
            app.Button19.Visible = 'off';
            app.Button19.Text = 'Button1';
            app.Button20 = uibutton(app.UIFigure, 'push');
            app.Button20.Visible = 'off';
            app.Button20.Text = 'Button1';
            app.Button10 = uibutton(app.UIFigure, 'push');
            app.Button10.Visible = 'off';
            app.Button10.Text = 'Button10';
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.Visible = 'off';
            app.Button1.Text = 'Button1';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Visible = 'off';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.Visible = 'off';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.Visible = 'off';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.Visible = 'off';
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.Visible = 'off';
            app.Button6.Text = 'Button6';
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.Visible = 'off';
            app.Button7.Text = 'Button7';
            app.Button8 = uibutton(app.UIFigure, 'push');
            app.Button8.Visible = 'off';
            app.Button8.Text = 'Button8';
            app.Button9 = uibutton(app.UIFigure, 'push');
            app.Button9.Visible = 'off';
            app.Button9.Text = 'Button9';
            app.card13Button = uibutton(app.UIFigure, 'state');
            app.card13Button.Enable = 'off';
            app.card13Button.Visible = 'off';
            app.card13Button.Text = 'card13';
            app.card14Button = uibutton(app.UIFigure, 'state');
            app.card14Button.Enable = 'off';
            app.card14Button.Visible = 'off';
            app.card14Button.Text = 'card14';
            app.card15Button = uibutton(app.UIFigure, 'state');
            app.card15Button.Enable = 'off';
            app.card15Button.Visible = 'off';
            app.card15Button.Text = 'card15';
            app.card16Button = uibutton(app.UIFigure, 'state');
            app.card16Button.Enable = 'off';
            app.card16Button.Visible = 'off';
            app.card16Button.Text = 'card16';
            app.card17Button = uibutton(app.UIFigure, 'state');
            app.card17Button.Enable = 'off';
            app.card17Button.Visible = 'off';
            app.card17Button.Text = 'card17';
            app.card18Button = uibutton(app.UIFigure, 'state');
            app.card18Button.Enable = 'off';
            app.card18Button.Visible = 'off';
            app.card18Button.Text = 'card18';
            app.card19Button = uibutton(app.UIFigure, 'state');
            app.card19Button.Enable = 'off';
            app.card19Button.Visible = 'off';
            app.card19Button.Text = 'card19';
            app.card20Button = uibutton(app.UIFigure, 'state');
            app.card20Button.Enable = 'off';
            app.card20Button.Visible = 'off';
            app.card20Button.Text = 'card20';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'back.jpg';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'back.jpg';
            app.Cardnum1 = uilabel(app.UIFigure);
            app.Cardnum1.Visible = 'off';
            app.Cardnum1.Text = 'Cards num in hands 1';
            app.Cardnum2 = uilabel(app.UIFigure);
            app.Cardnum2.Visible = 'off';
            app.Cardnum2.Text = 'Cards num in hands 2';
            app.nwin = uiimage(app.UIFigure);
            app.nwin.Visible = 'off';
            app.nwin.ImageSource = 'nwin.jpg';
            app.dwin = uiimage(app.UIFigure);
            app.dwin.Visible = 'off';
            app.dwin.ImageSource = 'dwin.jpg';
            app.alarm1 = uiimage(app.UIFigure);
            app.alarm1.Visible = 'off';
            app.alarm1.ImageSource = 'alarm.jpg';
            app.alarm2 = uiimage(app.UIFigure);
            app.alarm2.Visible = 'off';
            app.alarm2.ImageSource = 'alarm.jpg';
            app.hatbefore = uiimage(app.UIFigure);
            app.hatbefore.Visible = 'off';
            app.hatbefore.ImageSource = 'hatbefore.jpg';
            app.hatafter = uiimage(app.UIFigure);
            app.hatafter.Visible = 'off';
            app.hatafter.ImageSource = 'hatafter.jpg';
            app.hatme = uiimage(app.UIFigure);
            app.hatme.Visible = 'off';
            app.hatme.ImageSource = 'hatme.jpg';
            app.boom = uiimage(app.UIFigure);
            app.boom.Visible = 'off';
            app.boom.ImageSource = 'boom.jpg';
            app.wangzha = uiimage(app.UIFigure);
            app.wangzha.Visible = 'off';
            app.wangzha.ImageSource = 'wangzha.jpg';
            app.airplane = uiimage(app.UIFigure);
            app.airplane.Visible = 'off';
            app.airplane.ImageSource = 'airplane.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = playerUIfntest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end