classdef playerUItest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LordButton            matlab.ui.control.StateButton
        giveupButton          matlab.ui.control.StateButton
        NameLabel             matlab.ui.control.Label
        NameEditFieldLabel    matlab.ui.control.Label
        NameEditField         matlab.ui.control.EditField
        startButton           matlab.ui.control.Button
        beforePlayerLabel     matlab.ui.control.Label
        afterPlayerLabel      matlab.ui.control.Label
        Cardnum1              matlab.ui.control.Label
        Cardnum2              matlab.ui.control.Label
        Player1ActorLabel     matlab.ui.control.Label
        Player2ActorLabel     matlab.ui.control.Label
        SystemMessagePanel    matlab.ui.container.Panel
        MessageTextAreaLabel  matlab.ui.control.Label
        MessageTextArea       matlab.ui.control.TextArea
        SubmitButton          matlab.ui.control.Button
        YourCardsPanel        matlab.ui.container.Panel
        card1Button           matlab.ui.control.StateButton
        card2Button           matlab.ui.control.StateButton
        card3Button           matlab.ui.control.StateButton
        card4Button           matlab.ui.control.StateButton
        card5Button           matlab.ui.control.StateButton
        card6Button           matlab.ui.control.StateButton
        card7Button           matlab.ui.control.StateButton
        card8Button           matlab.ui.control.StateButton
        card9Button           matlab.ui.control.StateButton
        card10Button          matlab.ui.control.StateButton
        card11Button          matlab.ui.control.StateButton
        card12Button          matlab.ui.control.StateButton
        card13Button          matlab.ui.control.StateButton
        card14Button          matlab.ui.control.StateButton
        card15Button          matlab.ui.control.StateButton
        card16Button          matlab.ui.control.StateButton
        card17Button          matlab.ui.control.StateButton
        card18Button          matlab.ui.control.StateButton
        card19Button          matlab.ui.control.StateButton
        card20Button          matlab.ui.control.StateButton
        playButton            matlab.ui.control.Button
        passButton            matlab.ui.control.Button
        myActor               matlab.ui.control.Label
        me                    matlab.ui.control.Label
        cardsontablePanel     matlab.ui.container.Panel
        Button1               matlab.ui.control.Button
        Button2               matlab.ui.control.Button
        Button3               matlab.ui.control.Button
        Button4               matlab.ui.control.Button
        Button5               matlab.ui.control.Button
        Button6               matlab.ui.control.Button
        Button7               matlab.ui.control.Button
        Button8               matlab.ui.control.Button
        Button9               matlab.ui.control.Button
        Button10              matlab.ui.control.Button
        Button11              matlab.ui.control.Button
        Button12              matlab.ui.control.Button
        Button13              matlab.ui.control.Button
        Button14              matlab.ui.control.Button
        Button15              matlab.ui.control.Button
        Button16              matlab.ui.control.Button
        Button17              matlab.ui.control.Button
        Button18              matlab.ui.control.Button
        Button19              matlab.ui.control.Button
        Button20              matlab.ui.control.Button
        ButtonMe              matlab.ui.control.Button
        ButtonBefore          matlab.ui.control.Button
        ButtonAfter           matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        clockLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        start;
        MasterProcessor; 
        % param for rob 
     %   firstDecide;
     %   lastDecide;
        currentDecider;
        %%%
        lord;
        cardList;     % list of cards in hands
        buttonlist;   % list of cards on desk
        lordState;
        
        playCards;
        lastCards;
        
       
    end
    
    methods (Access = public)
        
        function head(app,list)
        app.ButtonBefore.Icon=[int2str(list(1)) '.jpg'];
        app.ButtonAfter.Icon=[int2str(list(2)) '.jpg'];
        app.ButtonMe.Icon=[int2str(list(3)) '.jpg'];  
        end
        
        function updatePlayer(app, Aafter, Abefore, Aself)
            if Aself == 0
                app.myActor.Text = 'Farmer';
            elseif Aself == 1
                app.myActor.Text = 'Lord';
            end
            app.myActor.Visible='on';
            app.beforePlayerLabel.Visible = 'on';
            app.Player1ActorLabel.Visible = 'on';
            if Abefore == 0
                app.Player1ActorLabel.Text = 'Farmer';
            elseif Abefore == 1
                app.Player1ActorLabel.Text = 'Lord';
            end
            %%
            app.afterPlayerLabel.Visible = 'on';
            app.Player2ActorLabel.Visible = 'on';
            if Aafter == 0
                app.Player2ActorLabel.Text = 'Farmer';
            elseif Aafter == 1
                app.Player2ActorLabel.Text = 'Lord';
            end
            %%
            app.LordButton.Visible='off';
            app.LordButton.Enable='off';
            app.giveupButton.Visible='off';
            app.giveupButton.Enable='off';
            app.MessageTextArea.Value = 'someone is choosing cards ';
        end
        
        function   showCardsButton(app,noskip)
            app.playButton.Visible='on';
            app.playButton.Enable='on';
            if ~noskip
                app.passButton.Visible='on';
                app.passButton.Enable='on';
            end
            for i=1:20
               % app.cardList{i}.Visible = 'on';
                app.cardList{i}.Enable = 'on';
            end
            
        end


        function showCards(app, split, Np1, Np2)
            app.Cardnum1.Visible = 'on';
            app.Cardnum1.Text = int2str(Np1);
            app.Cardnum2.Visible = true;
            app.Cardnum2.Text = int2str(Np2);
   
            for i = 1:20
                if i <= length(split)
                    app.cardList{i}.Visible = 'on';
                    app.cardList{i}.Text = int2str(split(i));
                    app.cardList{i}.Icon = [int2str(split(i)) '.jpg'];
                else 
                    app.cardList{i}.Visible = 'off';
                end             
            end

        end
    
        
        function showLC(app,lordCards,flag)
            if flag
                app.Button1.Text = int2str(lordCards(1));
                app.Button1.Icon = [int2str(lordCards(1)) '.jpg'];
                app.Button2.Text = int2str(lordCards(2));
                app.Button2.Icon = [int2str(lordCards(2)) '.jpg'];
                app.Button3.Text = int2str(lordCards(3));
                app.Button3.Icon = [int2str(lordCards(3)) '.jpg'];
                app.Button1.Visible = 'on';
                app.Button2.Visible = 'on';
                app.Button3.Visible = 'on';
            else 
                app.Button1.Visible = 'off';
                app.Button2.Visible = 'off';
                app.Button3.Visible = 'off';
            end                 
        end

    
        function showRob(app,currentDecider)
            app.LordButton.Visible='on';
            app.LordButton.Enable='on';
            app.giveupButton.Visible='on';
            app.giveupButton.Enable='on';
         %   app.firstDecide=firstDecide;
         %   app.lastDecide=lastDecide;
            app.currentDecider=currentDecider;            
        end
        
        
        function getLordState(app,over)
            if over
                app.LordButton.Visible = 'off';
                app.giveupButton.Visible = 'off';     
            else
                app.LordButton.Visible = 'on';
                app.giveupButton.Visible = 'on';
                app.SubmitButton.Visible = 'on';
            end          
        end
        
        
        function gameover(app,winner)
            app.MessageTextArea.Value =  ['game over ' winner ' is the winner'] ;    
            
        end
        
       
        function twopass(app)
            app.lastCards=[];
            app.passButton.Enable='off';  % two people pass,you can not pass
            app.MessageTextArea.Value="twopasss hh";
        end


        function [kindArray]=kind(app,x)
         
            tempArray=[];
            size=length(x);
            if size==0
                kindArray=[0,0];
            else
                
                for i=1:size
                    if x(i)==53 || x(i)==54
                        tempArray(end+1) = x(i);
                    else
                       temp = fix(x(i)/4)+3;
                       if mod(x(i),4)==0
                           temp=temp-1;    
                       end
                       tempArray(end+1) = temp;   %change num to cards 3-15 tempArray
                            
                    end
                end
            end
           
            
            if size==2
                 if x(1)==53 & x(2)==54
                    kindArray=[9,66];    %wangzha 
                 elseif  tempArray(1)==tempArray(2)
                    kindArray=[2,tempArray(1)];
                 else 
                    kindArray=[-1,-1];
                 end                              
           
            
            elseif size==1
                
                kindArray=[1,tempArray(1)];
                          
            elseif size>=3
                numResult=zeros(15);   %numresult record every num appear how many times 0 1 2 is always zero
                for i =3:15
                    count = 0;
                    for j=1:length(tempArray)
                        if tempArray(j)==i
                            count=count+1;
                        end
                    end
                    numResult(i)=count;               
                end
                
              %  for i =3:15
              %      app.MessageTextArea.Value=['numresult',int2str(i)+":" + int2str( numResult(i) )];
              %      pause(0.6);
              %  end
                
            
                if size==4
                    if ismember(4,numResult)        
                       address=find(numResult==4);
                       kindArray=[8,address(1)];   %address is the card's num
                    elseif ismember(3,numResult)
                       address = find(numResult==3);
                       kindArray=[5,address(1)];
                    else 
                        kindArray=[-1,-1];   
                    end 
               
                   % elseif (num1==1&&num2==3) || (num1==3 && num2==1)
                   
                elseif size==5
                    shunzi=[];
                    for i=3:15
                        if numResult(i)==1
                            shunzi(end+1)=i;          
                        end
                    end
                    
                    if length(shunzi)~=5
                        kindArray=[-1,-1];
                    else
                        flag=1;
                        for j=2:5
                            if shunzi(j)~=shunzi(j-1)+1
                                flag=0;
                            end
                        end
                        
                        if flag 
                            kindArray=[3,shunzi(1)];   %the smallest num in shunzi
                        else 
                            kindArray=[-1,-1];
                        end
                    end
                 % from size>=6 mark the biiger num   
                elseif size==6
                  if  ismember(4,numResult)  % sidaier
                      address=find(numResult==4);        
                      kindArray=[6,address(1)];   
                  elseif ismember(2,numResult)  
                      count2=0;
                      for i=3:15
                          if numResult(i)==2
                              count2=count2+1;
                              biggest=i;
                          end
                      end
                      
                      if count2==3
                          kindArray=[4,biggest];  %the bigest num in shuangshunzi
                      else
                          kindArray=[-1,-1];
                      end
                  else 
                      kindArray=[-1,-1]; 
                          
                  end
               
                    
                elseif size==8
                    
                    if ismember(3,numResult)
                        count3=0;
                       for i=3:15
                          if numResult(i)==3
                              count3=count3+1;
                              biggest=i;
                          end
                       end   
                      if count3==2
                          kindArray=[7,biggest] ;%feiji 
                      else
                          kindArray=[-1,-1];
                      end
                    else
                         kindArray=[-1,-1];   
                    end
                    
               else
                    kindArray=[-1,-1];   %if not in ant type it is ilegal 
               end
                  
            end         
        end
        
%         function showPlayButton(app)
%             app.playCards=[];
%             app.playButton.Visible = 'on';
%            
% 
%             for i=1:20
%                 if app.cardList{i}.Visible & app.cardList{i}.Value %when we press button the value will become 1
%                     app.playCards(end+1)=str2num(app.cardList{i}.Text);
%                 end
%             end
%             
%             
%             size=length(app.playCards);
%             tempArray=[];
%             
%             for i=1:size
%                 temp = fix(app.playCards(i)/4)+3;
%                 if mod(app.playCards(i),4)==0
%                    temp=temp-1;    
%                 end
%                 tempArray(end+1) = temp;
% 
%             end
%             
%             if size==2
%                  if app.playCards(1)==53 & app.playCards(2)==54
%                       app.playButton.Enable='on';    
%                  end
%                    
%             elseif ~isempty(app.playCards) & length(app.playCards)>=length(app.lastCards)
%             
%                 if size==1
%                     app.MessageTextArea.Value = [  'onecard',int2str(size)];
%                     app.playButton.Enable='on';
%                     
%                 elseif size==2
%                     app.MessageTextArea.Value = [  'twocards'];
%                     if app.playCards(1)==app.playCards(2)
%                         app.playButton.Enable='on';
%                     end    
%                 elseif  size==3
%                     app.MessageTextArea.Value = [  'threecards'];
%                     if app.playCards(1)==app.playCards(2)==app.playCards(3)
%                         app.playButton.Enable='on';
%                     end       
%                 elseif size==4
%                     numResult=zeros(13);
%                     for i =1:13
%                         count = 0;
%                         for j=1:length(tempArray)
%                             if tempArray(j)==i
%                                 count=count+1;
%                             end
%                         end
%                         numResult(i)=count;               
%                     end
%                 
%                 
%                     if ismember(4,numResult)        
%                        address=find(numResult==4);
%                        app.MessageTextArea.Value = [  'boom'];
%                        app.playButton.Enable='on';
%                     elseif ismember(3,numResult)
%                        app.playButton.Enable='on';
%                        app.MessageTextArea.Value = [ 'san dai yi'];
%                     end 
%                
%                    % elseif (num1==1&&num2==3) || (num1==3 && num2==1)
%                    
%                 elseif size==5
%                     app.playButton.Enable='on'; 
%                 elseif size==17
%                     app.playButton.Enable='on';
%                else
%                     app.playButton.Enable='off';
%                 end
%                   
%             end
%                % app.playButton.Enable='on';
%          
%                
%             
%         end
        
        function showDesk(app, split,mycards)
            for i =1:20
                app.buttonlist{i}.Visible='off';   
            end
            for i=length(mycards)+1:20
                app.cardList{i}.Visible='off';
            end
            
            for i=1:length(split)
                app.buttonlist{i}.Visible ='on';
                app.buttonlist{i}.Text =  int2str(split(i));
                app.buttonlist{i}.Icon = [int2str(split(i)) '.jpg'];
            end
            app.lastCards=split;
        end  
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, master)
          
            app.start = false;
            app.lord = false;
            app.lordState = false;
            app.lastCards=[];
            app.playCards=[];
            app.cardList = {app.card1Button,app.card2Button,app.card3Button,app.card4Button,app.card5Button,...
                app.card6Button,app.card7Button,app.card8Button,app.card9Button,app.card10Button,...
                app.card11Button,app.card12Button,app.card13Button,app.card14Button,app.card15Button,...
                app.card16Button,app.card17Button,app.card18Button,app.card19Button,app.card20Button};
        
            app.buttonlist={app.Button1,app.Button2,app.Button3,app.Button4,app.Button5,app.Button6,...
                app.Button7,app.Button8,app.Button9,app.Button10,app.Button11,app.Button12,app.Button13...
                app.Button14,app.Button15,app.Button16,app.Button17,app.Button18,app.Button19,app.Button20,};
            app.MessageTextArea.Value = 'press start button.';
        end

        % Value changed function: MessageTextArea
        function MessageTextAreaValueChanged(app, event)
            value = app.MessageTextArea.Value;
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            for i=1:20
                app.buttonlist{i}.Visible='off';
            end

            app.MessageTextArea.Value = 'welcome to this trash pocker game !' ;
            temp = app.MasterProcessor.everyone_ready+1;
            app.MasterProcessor.everyone_ready=temp;
            app.startButton.Visible = 'off';
            if temp==3
                app.MasterProcessor.startGame;
                app.start = true;
            end
                 
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            
            canPlay=false;
            app.playCards = [];
            for i = 1:length(app.buttonlist)
                if app.cardList{i}.Visible & app.cardList{i}.Value
                   %when we press button the value will become 1
                    app.playCards(end+1)=str2num(app.cardList{i}.Text);
                end
            end
            
            % judge is the cards legal

          
            lastkind=app.kind(app.lastCards);  %last cards  
            nowkind=app.kind(app.playCards);  %cards played   k小写

            if lastkind(1)==0         %if two pass or you are the first one, only need your play is legal
                if nowkind(1)<=0
                    app.MessageTextArea.Value="illegal cards, choose again ";
                else 
                    canPlay=true;
                end 
            else 
                if nowkind(1)==9  %wanzgha
                    canPlay=true;
                elseif nowkind(1)==8  %boom
                    if lastkind(1)~=8
                        canPlay=true;    
                    else                             % last is boom too
                        if lastkind(2)<nowkind(2)
                            canPlay=true;
                        end
                    end
                elseif nowkind(1)==lastkind(1) && nowkind(2)>lastkind(2)
                    canPlay=true;          
                end
            end
   
                
            if canPlay
                % he may choose nothing but press the button
                
                app.playButton.Visible = 'off';
                app.playButton.Enable = 'off';
                app.passButton.Visible = 'off';
                app.passButton.Enable = 'off';          
    
                % make value back to 0
                for i = 1:20
                    app.cardList{i}.Value = 0;
                    app.cardList{i}.Enable='off';
                end
                app.MessageTextArea.Value = [  ' cards have been played:' app.playCards];
                pass=false;
                app.MasterProcessor.loop(pass);
            else 
                app.MessageTextArea.Value="illegal cards, choose again ";
            end
            
 
        end

        % Button pushed function: passButton
        function passButtonPushed(app, event)
            app.clockLabel.Visible='off';      
            app.playCards=[];
            app.playButton.Visible = 'off';
            app.playButton.Enable = 'off';
            app.passButton.Visible = 'off';
            app.passButton.Enable = 'off';  
            for i = 1:20
                app.cardList{i}.Value = 0;
                app.cardList{i}.Enable='off';
            end
            
            pass=true;
            app.MasterProcessor.loop(pass);
            
            
        end

        % Value changed function: LordButton
        function LordButtonValueChanged(app, event)
            %value = app.LordButton.Value;
            %app.LordButton.Visible='off';
            app.LordButton.Enable='off';
            app.giveupButton.Enable='off';
            decision=1;
            app.MasterProcessor.nextRob(app.currentDecider,decision);
        end

        % Value changed function: giveupButton
        function giveupButtonValueChanged(app, event)
           % value = app.giveupButton.Value;
            %app.giveupButton.Visible='off';
            app.LordButton.Enable='off';
            app.giveupButton.Enable='off';
            decision=0;
            app.MasterProcessor.nextRob(app.currentDecider,decision);
   
        end

        % Value changed function: card10Button, card11Button, 
        % card12Button, card13Button, card14Button, card15Button, 
        % card16Button, card17Button, card18Button, card19Button, 
        % card1Button, card20Button, card2Button, card3Button, 
        % card4Button, card5Button, card6Button, card7Button, 
        % card8Button, card9Button
        function card1ButtonValueChanged(app, event)
            value = app.card1Button.Value;
           % app.showPlayButton();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LordButton = uibutton(app.UIFigure, 'state');
            app.LordButton.ValueChangedFcn = createCallbackFcn(app, @LordButtonValueChanged, true);
            app.LordButton.Enable = 'off';
            app.LordButton.Visible = 'off';
            app.LordButton.Text = 'Lord';
            app.giveupButton = uibutton(app.UIFigure, 'state');
            app.giveupButton.ValueChangedFcn = createCallbackFcn(app, @giveupButtonValueChanged, true);
            app.giveupButton.Enable = 'off';
            app.giveupButton.Visible = 'off';
            app.giveupButton.Text = 'give up';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Visible = 'off';
            app.NameLabel.Text = 'Name';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Visible = 'off';
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Visible = 'off';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.beforePlayerLabel = uilabel(app.UIFigure);
            app.beforePlayerLabel.Text = 'beforePlayer';
            app.afterPlayerLabel = uilabel(app.UIFigure);
            app.afterPlayerLabel.Text = 'afterPlayer';
            app.Cardnum1 = uilabel(app.UIFigure);
            app.Cardnum1.Visible = 'off';
            app.Cardnum1.Text = 'Cards num in hands 1';
            app.Cardnum2 = uilabel(app.UIFigure);
            app.Cardnum2.Visible = 'off';
            app.Cardnum2.Text = 'Cards num in hands 2';
            app.Player1ActorLabel = uilabel(app.UIFigure);
            app.Player1ActorLabel.Visible = 'off';
            app.Player1ActorLabel.Text = 'Player1Actor';
            app.Player2ActorLabel = uilabel(app.UIFigure);
            app.Player2ActorLabel.Visible = 'off';
            app.Player2ActorLabel.Text = 'Player2Actor';
            app.SystemMessagePanel = uipanel(app.UIFigure);
            app.SystemMessagePanel.Title = 'System Message';
            app.MessageTextAreaLabel = uilabel(app.SystemMessagePanel);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.SystemMessagePanel);
            app.MessageTextArea.ValueChangedFcn = createCallbackFcn(app, @MessageTextAreaValueChanged, true);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.Visible = 'off';
            app.SubmitButton.Text = 'Submit';
            app.YourCardsPanel = uipanel(app.UIFigure);
            app.YourCardsPanel.Title = 'Your Cards';
            app.card1Button = uibutton(app.YourCardsPanel, 'state');
            app.card1Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card1Button.Enable = 'off';
            app.card1Button.Text = 'card1';
            app.card2Button = uibutton(app.YourCardsPanel, 'state');
            app.card2Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card2Button.Enable = 'off';
            app.card2Button.Text = 'card2';
            app.card3Button = uibutton(app.YourCardsPanel, 'state');
            app.card3Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card3Button.Enable = 'off';
            app.card3Button.Text = 'card3';
            app.card4Button = uibutton(app.YourCardsPanel, 'state');
            app.card4Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card4Button.Enable = 'off';
            app.card4Button.Text = 'card4';
            app.card5Button = uibutton(app.YourCardsPanel, 'state');
            app.card5Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card5Button.Enable = 'off';
            app.card5Button.Text = 'card5';
            app.card6Button = uibutton(app.YourCardsPanel, 'state');
            app.card6Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card6Button.Enable = 'off';
            app.card6Button.Text = 'card6';
            app.card7Button = uibutton(app.YourCardsPanel, 'state');
            app.card7Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card7Button.Enable = 'off';
            app.card7Button.Text = 'card7';
            app.card8Button = uibutton(app.YourCardsPanel, 'state');
            app.card8Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card8Button.Enable = 'off';
            app.card8Button.Text = 'card8';
            app.card9Button = uibutton(app.YourCardsPanel, 'state');
            app.card9Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card9Button.Enable = 'off';
            app.card9Button.Text = 'card9';
            app.card10Button = uibutton(app.YourCardsPanel, 'state');
            app.card10Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card10Button.Enable = 'off';
            app.card10Button.Text = 'card10';
            app.card11Button = uibutton(app.YourCardsPanel, 'state');
            app.card11Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card11Button.Enable = 'off';
            app.card11Button.Text = 'card11';
            app.card12Button = uibutton(app.YourCardsPanel, 'state');
            app.card12Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card12Button.Enable = 'off';
            app.card12Button.Text = 'card12';
            app.card13Button = uibutton(app.YourCardsPanel, 'state');
            app.card13Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card13Button.Enable = 'off';
            app.card13Button.Text = 'card13';
            app.card14Button = uibutton(app.YourCardsPanel, 'state');
            app.card14Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card14Button.Enable = 'off';
            app.card14Button.Text = 'card14';
            app.card15Button = uibutton(app.YourCardsPanel, 'state');
            app.card15Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card15Button.Enable = 'off';
            app.card15Button.Text = 'card15';
            app.card16Button = uibutton(app.YourCardsPanel, 'state');
            app.card16Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card16Button.Enable = 'off';
            app.card16Button.Text = 'card16';
            app.card17Button = uibutton(app.YourCardsPanel, 'state');
            app.card17Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card17Button.Enable = 'off';
            app.card17Button.Text = 'card17';
            app.card18Button = uibutton(app.YourCardsPanel, 'state');
            app.card18Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card18Button.Enable = 'off';
            app.card18Button.Text = 'card18';
            app.card19Button = uibutton(app.YourCardsPanel, 'state');
            app.card19Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card19Button.Enable = 'off';
            app.card19Button.Text = 'card19';
            app.card20Button = uibutton(app.YourCardsPanel, 'state');
            app.card20Button.ValueChangedFcn = createCallbackFcn(app, @card1ButtonValueChanged, true);
            app.card20Button.Enable = 'off';
            app.card20Button.Text = 'card20';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Enable = 'off';
            app.playButton.Visible = 'off';
            app.playButton.Text = 'play';
            app.passButton = uibutton(app.UIFigure, 'push');
            app.passButton.ButtonPushedFcn = createCallbackFcn(app, @passButtonPushed, true);
            app.passButton.Enable = 'off';
            app.passButton.Visible = 'off';
            app.passButton.Text = 'pass';
            app.myActor = uilabel(app.UIFigure);
            app.myActor.Visible = 'off';
            app.myActor.Text = 'Actor';
            app.me = uilabel(app.UIFigure);
            app.me.Text = 'me';
            app.cardsontablePanel = uipanel(app.UIFigure);
            app.cardsontablePanel.Title = 'cards on table';
            app.Button1 = uibutton(app.cardsontablePanel, 'push');
            app.Button1.Visible = 'off';
            app.Button1.Text = 'Button1';
            app.Button2 = uibutton(app.cardsontablePanel, 'push');
            app.Button2.Visible = 'off';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.cardsontablePanel, 'push');
            app.Button3.Visible = 'off';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.cardsontablePanel, 'push');
            app.Button4.Visible = 'off';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.cardsontablePanel, 'push');
            app.Button5.Visible = 'off';
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.cardsontablePanel, 'push');
            app.Button6.Visible = 'off';
            app.Button6.Text = 'Button6';
            app.Button7 = uibutton(app.cardsontablePanel, 'push');
            app.Button7.Visible = 'off';
            app.Button7.Text = 'Button7';
            app.Button8 = uibutton(app.cardsontablePanel, 'push');
            app.Button8.Visible = 'off';
            app.Button8.Text = 'Button8';
            app.Button9 = uibutton(app.cardsontablePanel, 'push');
            app.Button9.Visible = 'off';
            app.Button9.Text = 'Button9';
            app.Button10 = uibutton(app.cardsontablePanel, 'push');
            app.Button10.Visible = 'off';
            app.Button10.Text = 'Button10';
            app.Button11 = uibutton(app.cardsontablePanel, 'push');
            app.Button11.Visible = 'off';
            app.Button11.Text = 'Button11';
            app.Button12 = uibutton(app.cardsontablePanel, 'push');
            app.Button12.Visible = 'off';
            app.Button12.Text = 'Button12';
            app.Button13 = uibutton(app.cardsontablePanel, 'push');
            app.Button13.Visible = 'off';
            app.Button13.Text = 'Button13';
            app.Button14 = uibutton(app.cardsontablePanel, 'push');
            app.Button14.Visible = 'off';
            app.Button14.Text = 'Button14';
            app.Button15 = uibutton(app.cardsontablePanel, 'push');
            app.Button15.Visible = 'off';
            app.Button15.Text = 'Button15';
            app.Button16 = uibutton(app.cardsontablePanel, 'push');
            app.Button16.Visible = 'off';
            app.Button16.Text = 'Button16';
            app.Button17 = uibutton(app.cardsontablePanel, 'push');
            app.Button17.Visible = 'off';
            app.Button17.Text = 'Button17';
            app.Button18 = uibutton(app.cardsontablePanel, 'push');
            app.Button18.Visible = 'off';
            app.Button18.Text = 'Button18';
            app.Button19 = uibutton(app.cardsontablePanel, 'push');
            app.Button19.Visible = 'off';
            app.Button19.Text = 'Button19';
            app.Button20 = uibutton(app.cardsontablePanel, 'push');
            app.Button20.Visible = 'off';
            app.Button20.Text = 'Button20';
            app.ButtonMe = uibutton(app.UIFigure, 'push');
            app.ButtonMe.Icon = 'chushi.jpg';
            app.ButtonMe.Text = '';
            app.ButtonBefore = uibutton(app.UIFigure, 'push');
            app.ButtonBefore.Icon = 'chushi.jpg';
            app.ButtonBefore.Text = '';
            app.ButtonAfter = uibutton(app.UIFigure, 'push');
            app.ButtonAfter.Icon = 'chushi.jpg';
            app.ButtonAfter.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'clock1.jpg';
            app.clockLabel = uilabel(app.UIFigure);
            app.clockLabel.Visible = 'off';
            app.clockLabel.Text = '25';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = playerUItest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end